classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SquareButton          matlab.ui.control.Button
        LargeButton           matlab.ui.control.Button
        MediumButton          matlab.ui.control.Button
        SmallButton           matlab.ui.control.Button
        ProfileLabel          matlab.ui.control.Label
        FigureformatterLabel  matlab.ui.control.Label
        Slider_ratio          matlab.ui.control.Slider
        RatioLabel            matlab.ui.control.Label
        WidthLabel            matlab.ui.control.Label
        PButton_FontSize_2    matlab.ui.control.Button
        MButton_FontSize_2    matlab.ui.control.Button
        Value_Width           matlab.ui.control.NumericEditField
        ReloadButton          matlab.ui.control.Button
        text_title            matlab.ui.control.EditField
        TitleLabel            matlab.ui.control.Label
        SaveButton            matlab.ui.control.Button
        FileName_text         matlab.ui.control.EditField
        FileNameLabel         matlab.ui.control.Label
        text_ylabel           matlab.ui.control.EditField
        YlabelLabel           matlab.ui.control.Label
        text_Xlabel           matlab.ui.control.EditField
        XlabelLabel           matlab.ui.control.Label
        LineWidthLabel        matlab.ui.control.Label
        Pbutton_lineWidth     matlab.ui.control.Button
        Mbutton_lineWidth     matlab.ui.control.Button
        Value_lineWidth       matlab.ui.control.NumericEditField
        MarkerSizeLabel       matlab.ui.control.Label
        Pbutton_markerSize    matlab.ui.control.Button
        MButton_MarkerSize    matlab.ui.control.Button
        Value_MarkerSize      matlab.ui.control.NumericEditField
        FontsizeLabel         matlab.ui.control.Label
        PButton_FontSize      matlab.ui.control.Button
        MButton_FontSize      matlab.ui.control.Button
        Value_FontSize        matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = ProfileApplier(app,prof)
            app.Value_Width.Value=prof.Value_Width.Value;
            Value_WidthValueChanged(app);
            event.Value=prof.ratio.Value;
            Slider_ratioValueChanging(app, event);
            set(findall(gcf,'-property','FontSize'),'FontSize',prof.FontSize);
            set(findall(gcf,'-property','MarkerSize'),'MarkerSize',prof.MarkerSize);
            set(findobj(gcf, 'type', 'line'),'LineWidth',prof.LineWidth);
            ReloadButtonPushed(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            A=gca;
            AA=findall(gcf,'-property','FontSize');
            G=gcf;
            app.Value_Width.Value=G.Position(3);
            app.Slider_ratio.Value=G.Position(3)/G.Position(4);
            app.Value_FontSize.Value=min(arrayfun(@(x)x.FontSize,AA));
            app.Value_MarkerSize.Value=min(arrayfun(@(x)x.MarkerSize,findobj(gcf, 'type', 'line')));
            app.Value_lineWidth.Value=min(arrayfun(@(x)x.LineWidth,findobj(gcf, 'type', 'line')));
            app.text_Xlabel.Value=A.XLabel.String;
            Q=A.Title.String;
            if iscell(Q)
                Q=[sprintf('%s\\n',Q{1:end-1}),Q{end}];
            end
            app.text_title.Value= Q;
            app.text_ylabel.Value=A.YLabel.String;
           
        end

        % Button pushed function: PButton_FontSize
        function PButton_FontSizePushed(app, event)
            app.Value_FontSize.Value=app.Value_FontSize.Value+1;
            app.Value_FontSizeValueChanged;
        end

        % Button pushed function: Pbutton_markerSize
        function Pbutton_markerSizePushed(app, event)
            app.Value_MarkerSize.Value=app.Value_MarkerSize.Value+1;
            Value_MarkerSizeValueChanged(app, event)
        end

        % Button pushed function: Pbutton_lineWidth
        function Pbutton_lineWidthPushed(app, event)
            app.Value_lineWidth.Value=app.Value_lineWidth.Value+1;
            Value_lineWidthValueChanged(app, event)
        end

        % Button pushed function: MButton_FontSize
        function MButton_FontSizePushed(app, event)
            app.Value_FontSize.Value=app.Value_FontSize.Value-1;
            app.Value_FontSizeValueChanged;
        end

        % Button pushed function: MButton_MarkerSize
        function MButton_MarkerSizePushed(app, event)
            app.Value_MarkerSize.Value=app.Value_MarkerSize.Value-1;
            Value_MarkerSizeValueChanged(app, event)
        end

        % Button pushed function: Mbutton_lineWidth
        function Mbutton_lineWidthPushed(app, event)
            app.Value_lineWidth.Value=app.Value_lineWidth.Value-1;
            Value_lineWidthValueChanged(app, event);
        end

        % Button pushed function: ReloadButton
        function ReloadButtonPushed(app, event)
            A=gca;
            AA=findall(gcf,'-property','FontSize');
            G=gcf;
            app.Value_Width.Value=G.Position(3);
            app.Slider_ratio.Value=G.Position(3)/G.Position(4);
            app.Value_FontSize.Value=min(arrayfun(@(x)x.FontSize,AA));
            app.Value_MarkerSize.Value=min(arrayfun(@(x)x.MarkerSize,findobj(gcf, 'type', 'line')));
            app.Value_lineWidth.Value=min(arrayfun(@(x)x.LineWidth,findobj(gcf, 'type', 'line')));
            app.text_Xlabel.Value=A.XLabel.String;
            Q=A.Title.String;
            if iscell(Q)
                Q=[sprintf('%s\\n',Q{1:end-1}),Q{end}];
            end
            app.text_title.Value=Q;
            app.text_ylabel.Value=A.YLabel.String;
        end

        % Value changed function: Value_FontSize
        function Value_FontSizeValueChanged(app, event)
            value = app.Value_FontSize.Value;
            set(findall(gcf,'-property','FontSize'),'FontSize',value)
        end

        % Value changed function: Value_MarkerSize
        function Value_MarkerSizeValueChanged(app, event)
            value = app.Value_MarkerSize.Value;
            set(findall(gcf,'-property','MarkerSize'),'MarkerSize',value)
        end

        % Value changed function: Value_lineWidth
        function Value_lineWidthValueChanged(app, event)
            value = app.Value_lineWidth.Value;
            set(findobj(gcf, 'type', 'line'),'LineWidth',value);
        end

        % Value changing function: text_Xlabel
        function text_XlabelValueChanging(app, event)
            changingValue = event.Value;
            A=gca;
            A.XLabel.String=changingValue;
        end

        % Value changing function: text_ylabel
        function text_ylabelValueChanging(app, event)
            changingValue = event.Value;
            A=gca;
            A.YLabel.String=changingValue;
        end

        % Value changing function: text_title
        function text_titleValueChanging(app, event)
            changingValue = event.Value;
            A=gca;
            A.Title.String=sprintf(changingValue);
        end

        % Value changing function: Slider_ratio
        function Slider_ratioValueChanging(app, event)
            changingValue = event.Value;
            G=gcf;
            posTemp=G.Position;
            G.Position(4)=round(G.Position(3)/changingValue);
            G=gcf; G.Position;
            drawnow();
            G.Position(2)=posTemp(2);
        end

        % Value changed function: Value_Width
        function Value_WidthValueChanged(app, event)
            value = app.Value_Width.Value;
            G=gcf;
            G.Position(3)=value;
            event2.Value=app.Slider_ratio.Value;
            Slider_ratioValueChanging(app,event2);
        end

        % Button pushed function: MButton_FontSize_2
        function MButton_FontSize_2Pushed(app, event)
            app.Value_Width.Value=app.Value_Width.Value-10;
            Value_WidthValueChanged(app);
        end

        % Button pushed function: PButton_FontSize_2
        function PButton_FontSize_2Pushed(app, event)
            app.Value_Width.Value=app.Value_Width.Value+10;
            Value_WidthValueChanged(app);
        end

        % Button pushed function: SmallButton
        function SmallButtonPushed(app, event)
            prof.Value_Width.Value=540;
            prof.ratio.Value=1.618;
            prof.FontSize=14;
            prof.MarkerSize=5;
            prof.LineWidth=2;
            ProfileApplier(app,prof)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Value_FontSize = uieditfield(app.UIFigure, 'numeric');
            app.Value_FontSize.ValueChangedFcn = createCallbackFcn(app, @Value_FontSizeValueChanged, true);
            app.MButton_FontSize = uibutton(app.UIFigure, 'push');
            app.MButton_FontSize.ButtonPushedFcn = createCallbackFcn(app, @MButton_FontSizePushed, true);
            app.MButton_FontSize.Text = '-';
            app.PButton_FontSize = uibutton(app.UIFigure, 'push');
            app.PButton_FontSize.ButtonPushedFcn = createCallbackFcn(app, @PButton_FontSizePushed, true);
            app.PButton_FontSize.Text = {'+'; ''};
            app.FontsizeLabel = uilabel(app.UIFigure);
            app.FontsizeLabel.Text = 'Fontsize';
            app.Value_MarkerSize = uieditfield(app.UIFigure, 'numeric');
            app.Value_MarkerSize.ValueChangedFcn = createCallbackFcn(app, @Value_MarkerSizeValueChanged, true);
            app.MButton_MarkerSize = uibutton(app.UIFigure, 'push');
            app.MButton_MarkerSize.ButtonPushedFcn = createCallbackFcn(app, @MButton_MarkerSizePushed, true);
            app.MButton_MarkerSize.Text = '-';
            app.Pbutton_markerSize = uibutton(app.UIFigure, 'push');
            app.Pbutton_markerSize.ButtonPushedFcn = createCallbackFcn(app, @Pbutton_markerSizePushed, true);
            app.Pbutton_markerSize.Text = {'+'; ''};
            app.MarkerSizeLabel = uilabel(app.UIFigure);
            app.MarkerSizeLabel.Text = 'MarkerSize';
            app.Value_lineWidth = uieditfield(app.UIFigure, 'numeric');
            app.Value_lineWidth.ValueChangedFcn = createCallbackFcn(app, @Value_lineWidthValueChanged, true);
            app.Mbutton_lineWidth = uibutton(app.UIFigure, 'push');
            app.Mbutton_lineWidth.ButtonPushedFcn = createCallbackFcn(app, @Mbutton_lineWidthPushed, true);
            app.Mbutton_lineWidth.Text = '-';
            app.Pbutton_lineWidth = uibutton(app.UIFigure, 'push');
            app.Pbutton_lineWidth.ButtonPushedFcn = createCallbackFcn(app, @Pbutton_lineWidthPushed, true);
            app.Pbutton_lineWidth.Text = {'+'; ''};
            app.LineWidthLabel = uilabel(app.UIFigure);
            app.LineWidthLabel.Text = 'LineWidth';
            app.XlabelLabel = uilabel(app.UIFigure);
            app.XlabelLabel.Text = 'Xlabel';
            app.text_Xlabel = uieditfield(app.UIFigure, 'text');
            app.text_Xlabel.ValueChangingFcn = createCallbackFcn(app, @text_XlabelValueChanging, true);
            app.YlabelLabel = uilabel(app.UIFigure);
            app.YlabelLabel.Text = 'Ylabel';
            app.text_ylabel = uieditfield(app.UIFigure, 'text');
            app.text_ylabel.ValueChangingFcn = createCallbackFcn(app, @text_ylabelValueChanging, true);
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'FileName';
            app.FileName_text = uieditfield(app.UIFigure, 'text');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Title';
            app.text_title = uieditfield(app.UIFigure, 'text');
            app.text_title.ValueChangingFcn = createCallbackFcn(app, @text_titleValueChanging, true);
            app.ReloadButton = uibutton(app.UIFigure, 'push');
            app.ReloadButton.ButtonPushedFcn = createCallbackFcn(app, @ReloadButtonPushed, true);
            app.ReloadButton.Text = 'Reload';
            app.Value_Width = uieditfield(app.UIFigure, 'numeric');
            app.Value_Width.ValueChangedFcn = createCallbackFcn(app, @Value_WidthValueChanged, true);
            app.MButton_FontSize_2 = uibutton(app.UIFigure, 'push');
            app.MButton_FontSize_2.ButtonPushedFcn = createCallbackFcn(app, @MButton_FontSize_2Pushed, true);
            app.MButton_FontSize_2.Text = '-';
            app.PButton_FontSize_2 = uibutton(app.UIFigure, 'push');
            app.PButton_FontSize_2.ButtonPushedFcn = createCallbackFcn(app, @PButton_FontSize_2Pushed, true);
            app.PButton_FontSize_2.Text = {'+'; ''};
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = 'Width';
            app.RatioLabel = uilabel(app.UIFigure);
            app.RatioLabel.Text = 'Ratio';
            app.Slider_ratio = uislider(app.UIFigure);
            app.Slider_ratio.Limits = [0.2 3];
            app.Slider_ratio.MajorTicks = [0.2 1 1.618 2 3 5];
            app.Slider_ratio.MajorTickLabels = {'0.2', '1', 'f', '2', '3', '5'};
            app.Slider_ratio.ValueChangingFcn = createCallbackFcn(app, @Slider_ratioValueChanging, true);
            app.Slider_ratio.MinorTicks = [];
            app.Slider_ratio.Value = 1.5;
            app.FigureformatterLabel = uilabel(app.UIFigure);
            app.FigureformatterLabel.Text = 'Figure formatter';
            app.ProfileLabel = uilabel(app.UIFigure);
            app.ProfileLabel.Text = 'Profile';
            app.SmallButton = uibutton(app.UIFigure, 'push');
            app.SmallButton.ButtonPushedFcn = createCallbackFcn(app, @SmallButtonPushed, true);
            app.SmallButton.Text = 'Small';
            app.MediumButton = uibutton(app.UIFigure, 'push');
            app.MediumButton.Text = 'Medium';
            app.LargeButton = uibutton(app.UIFigure, 'push');
            app.LargeButton.Text = 'Large';
            app.SquareButton = uibutton(app.UIFigure, 'push');
            app.SquareButton.Text = 'Square';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end